classdef waterDetectorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaterDetectorGUIUIFigure  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenGeotifImageMenu       matlab.ui.container.Menu
        OpenCTRMenu               matlab.ui.container.Menu
        ChangeCTRfieldsMenu       matlab.ui.container.Menu
        ShapeFileMenu             matlab.ui.container.Menu
        SaveCTRMenu               matlab.ui.container.Menu
        ResetAllMenu              matlab.ui.container.Menu
        ImageMenu                 matlab.ui.container.Menu
        CropMenu                  matlab.ui.container.Menu
        ResetImageSizeMenu        matlab.ui.container.Menu
        MaskMenu                  matlab.ui.container.Menu
        AddmaskMenu               matlab.ui.container.Menu
        byshapefileMenu           matlab.ui.container.Menu
        byeditorMenu              matlab.ui.container.Menu
        byCTRfieldsMenu           matlab.ui.container.Menu
        RemovemaskMenu            matlab.ui.container.Menu
        byshapefileMenu_2         matlab.ui.container.Menu
        byeditorMenu_2            matlab.ui.container.Menu
        byCTRfieldsMenu_2         matlab.ui.container.Menu
        ShowMaskMenu              matlab.ui.container.Menu
        MorphologyMenu            matlab.ui.container.Menu
        CleaningMenu              matlab.ui.container.Menu
        ClosingMenu               matlab.ui.container.Menu
        CropUIAxes                matlab.ui.control.UIAxes
        IndexUIAxes               matlab.ui.control.UIAxes
        MinSliderLabel            matlab.ui.control.Label
        MinSlider                 matlab.ui.control.Slider
        MaxSliderLabel            matlab.ui.control.Label
        MaxSlider                 matlab.ui.control.Slider
        DropDown                  matlab.ui.control.DropDown
        ImageUIAxes               matlab.ui.control.UIAxes
        MaxField                  matlab.ui.control.NumericEditField
        MinField                  matlab.ui.control.NumericEditField
        PreviewUIAxes             matlab.ui.control.UIAxes
        CreateShapeButton         matlab.ui.control.Button
        ShowImageButton           matlab.ui.control.Button
        ShowPreviewButton         matlab.ui.control.Button
        ShowNDVIButton            matlab.ui.control.Button
        ShowCropButton            matlab.ui.control.Button
        numFindLabel              matlab.ui.control.Label
        FindDropDown              matlab.ui.control.DropDown
        UseasmaskButton           matlab.ui.control.Button
        numCTRLabel               matlab.ui.control.Label
        CTRDropDown               matlab.ui.control.DropDown
        AddtoshapeButton          matlab.ui.control.Button
        RemovetoctrButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        wd = WaterDetector() % water detector object
        formatToSave = {'.jpeg','JPEG image';...
            '.png','PNG image';...
            '*.*',  'All Files (*.*)'};
        preview = [];
        ndvi = [];
        ctr = {};
        Selection = [];
        maskSelection = [];
    end
    
    methods (Access = private)
        
        function setMinThreshold(app,val)
            if (val>=-1 && val<=1)
                app.MaxSlider.Limits = [val, 1];
                app.MaxField.Limits = [val, 1];
            end
        end
        
        function setMaxThreshold(app,val)
            if (val>=-1 && val<=1)
                app.MinSlider.Limits = [-1, val];
                app.MinField.Limits = [-1, val];
            end
        end
        
        function resetImage(app,element,title)
            cla(element);
            reset(element);
            element.Box = 'on';
            element.Title.String = title;
        end
        
        function showPreview(app)
            app.preview = app.wd.getWater();
            mask = app.wd.getMask();
            crop = app.wd.getCrop();
            if (~isempty(app.preview))
                if (~isempty(mask))
                    if (~isempty(crop.image))
                        app.preview(mask(crop.yMin:crop.yMax,...
                            crop.xMin:crop.xMax)) = false;
                    else
                        app.preview(mask) = false;
                    end
                end
                imshow(app.preview,'Parent',app.PreviewUIAxes);
            end
        end
        
    end
    

    methods (Access = private)

        % Menu selected function: OpenGeotifImageMenu
        function OpenGeotifImageMenuSelected(app, event)
            [filename, path] = uigetfile({'*.tif',  'Geotif Image (*.tif)';...
                '*.*',  'All Files (*.*)'});
            if (ischar(filename) && ischar(path))
                eq = questdlg('Do you want to use equilized image?');
                msgbox(char("Please, don't touch anything!"),'Wait');
                if (eq == "Yes")
                    app.wd.readImage(strcat(path,filename),true);
                else
                    app.wd.readImage(strcat(path,filename));
                end
                close;
                i = app.wd.getImage();
                if (~isempty(i))
                    imshow(i,'Parent',app.ImageUIAxes);
                    app.MaskMenu.Enable = 'on';
                    app.ImageMenu.Enable = 'on';
                    app.DropDown.Enable = 'on';
                    app.resetImage(app.CropUIAxes,"Crop");
                    app.resetImage(app.IndexUIAxes,"Index");
                    app.resetImage(app.PreviewUIAxes,"Preview");
                    app.ShowImageButton.Enable = 'on';
                    app.ShowPreviewButton.Enable = 'off';
                    app.ShowCropButton.Enable = 'off';
                    app.ShowNDVIButton.Enable = 'off';
                    app.DropDown.Value = 'INDEX';
                    app.numFindLabel.Text = "";
                    app.FindDropDown.Visible = 'off';
                    app.FindDropDown.Items = {};
                    app.CTRDropDown.Visible = 'off';
                    app.CTRDropDown.Items = {};
                    app.UseasmaskButton.Visible = 'off';
                    app.OpenCTRMenu.Enable = 'on';
                    app.ResetAllMenu.Enable = 'on';
                    app.AddtoshapeButton.Visible = 'off';
                end
            end
        end

        % Menu selected function: byshapefileMenu
        function byshapefileMenuSelected(app, event)
            if (~isempty(app.wd.getImage()))
                [filename, path] = uigetfile({'*.shp',  'Shape file (*.shp)';...
                    '*.*',  'All Files (*.*)'});
                if (ischar(filename) && ischar(path))
                    shapemask = shaperead(strcat(path,filename));
                    if (exist('shapemask','var') || ~isempty(shapemask))
                        msgbox(char("Please, don't touch anything!"),'Wait');
                        app.wd.shape2mask(shapemask);
                        if (app.RemovemaskMenu.Enable == "off")
                            app.RemovemaskMenu.Enable = 'on';
                        end
                        if (app.ShowMaskMenu.Enable == "off")
                            app.ShowMaskMenu.Enable = 'on';
                        end
                        close;
                        app.showPreview();
                    end
                end
            end
        end

        % Menu selected function: byeditorMenu
        function byeditorMenuSelected(app, event)
            if (~isempty(app.wd.getImage()))
                app.wd.applyMask();
                if (app.RemovemaskMenu.Enable == "off")
                    app.RemovemaskMenu.Enable = 'on';
                end
                if (app.ShowMaskMenu.Enable == "off")
                    app.ShowMaskMenu.Enable = 'on';
                end
                app.showPreview();
            end
        end

        % Menu selected function: byshapefileMenu_2
        function byshapefileMenu_2Selected(app, event)
            if (~isempty(app.wd.getMask()))
                [filename, path] = uigetfile({'*.shp',  'Shape file (*.shp)';...
                    '*.*',  'All Files (*.*)'});
                shapemask = shaperead(strcat(path,filename));
                if (exist('shapemask','var') || ~isempty(shapemask))
                    msgbox(char("Please, don't touch anything!"),'Wait');
                    app.wd.removeShapeMask(shapemask);
                    if (isempty(app.wd.getMask()))
                        app.RemovemaskMenu.Enable = 'off';
                        if (app.ShowMaskMenu.Enable == "on")
                            app.ShowMaskMenu.Enable = 'off';
                        end
                    end
                    close;
                    app.showPreview();
                end
            end
        end

        % Menu selected function: byeditorMenu_2
        function byeditorMenu_2Selected(app, event)
            if (~isempty(app.wd.getMask()))
                app.wd.removeMask();
                if (isempty(app.wd.getMask()))
                    app.RemovemaskMenu.Enable = 'off';
                    if (app.ShowMaskMenu.Enable == "on")
                        app.ShowMaskMenu.Enable = 'off';
                    end
                end
                app.showPreview();
            end
        end

        % Menu selected function: CropMenu
        function CropMenuSelected(app, event)
            if (~isempty(app.wd.getImage()))
                crop = app.wd.cropImage();
                if (~isempty(crop.image))
                    app.preview = [];
                    imshow(crop.image(:,:,1:3),'Parent',app.CropUIAxes);
                    if (~isempty(app.Selection))
                        list = unique(extractfield(app.ctr,'DESCR'));
                        num = app.wd.importCTR(app.ctr,string(list(app.Selection)));
                        shape = app.wd.cropCTR(list(app.Selection)');
                        app.numCTRLabel.Text = strcat(strcat("CTR: ",string(num(1))),strcat("; CTR Crop :",string(size(shape,1))));
                        if (~isempty(shape))
                            found = cell(size(shape));
                            found(:) = {" not found"};
                            found([shape(:).found])= {""};
                            app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                string(found'));
                            app.AddtoshapeButton.Visible = 'on';
                            app.RemovetoctrButton.Visible = 'on';
                            app.SaveCTRMenu.Enable = 'on';
                        else
                            app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                            app.AddtoshapeButton.Visible = 'off';
                            app.RemovetoctrButton.Visible = 'off';
                            app.SaveCTRMenu.Enable = 'on';
                        end
                    end
                    if (app.ResetImageSizeMenu.Enable == "off")
                        app.ResetImageSizeMenu.Enable = 'on';
                    end
                    app.resetImage(app.IndexUIAxes,"Index");
                    app.resetImage(app.PreviewUIAxes,"Preview");
                    app.ShowCropButton.Enable = 'on';
                    app.ShowPreviewButton.Enable = 'off';
                    app.ShowNDVIButton.Enable = 'off';
                    app.numFindLabel.Text = "";
                    app.FindDropDown.Visible = 'off';
                    app.FindDropDown.Items = {};
                    app.UseasmaskButton.Visible = 'off';
                    app.DropDown.Value = 'INDEX';
                    app.setMinThreshold(-1);
                    app.setMaxThreshold(1);
                    app.MinSlider.Value = 0;
                    app.MinField.Value = 0;
                    app.MaxSlider.Value = 0;
                    app.MaxField.Value = 0;
                    app.MinField.Enable = 'off';
                    app.MaxField.Enable = 'off';
                    app.MinSlider.Enable = 'off';
                    app.MaxSlider.Enable = 'off';
                    app.AddtoshapeButton.Visible = 'off';
                end
            end
        end

        % Menu selected function: ResetImageSizeMenu
        function ResetImageSizeMenuSelected(app, event)
            crop = app.wd.getCrop();
            if (~isempty(crop.image))
                app.wd.removeCrop();
                if (~isempty(app.Selection))
                    list = unique(extractfield(app.ctr,'DESCR'));
                    num = app.wd.importCTR(app.ctr,string(list(app.Selection)));
                    app.numCTRLabel.Text = strcat("CTR: ",string(num(1)));
                    shape = app.wd.getWaterFromCTR(list(app.Selection)');
                    if (~isempty(shape))
                        found = cell(size(shape));
                        found(:) = {" not found"};
                        found([shape(:).found])= {""};
                        app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                            string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                            string(found'));
                        app.AddtoshapeButton.Visible = 'on';
                        app.RemovetoctrButton.Visible = 'on';
                        app.SaveCTRMenu.Enable = 'on';
                    else
                        app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                        app.AddtoshapeButton.Visible = 'off';
                        app.RemovetoctrButton.Visible = 'off';
                        app.SaveCTRMenu.Enable = 'off';
                    end
                end
                app.ResetImageSizeMenu.Enable = "off";
                app.resetImage(app.CropUIAxes,"Crop");
                app.resetImage(app.IndexUIAxes,"Index");
                app.resetImage(app.PreviewUIAxes,"Preview");
                app.ShowCropButton.Enable = 'off';
                app.ShowPreviewButton.Enable = 'off';
                app.ShowNDVIButton.Enable = 'off';
                app.numFindLabel.Text = "";
                app.MorphologyMenu.Enable = 'off';
                app.CreateShapeButton.Enable = 'off';
                app.FindDropDown.Visible = 'off';
                app.FindDropDown.Items = {};
                app.UseasmaskButton.Visible = 'off';
                app.DropDown.Value = 'INDEX';
                app.setMinThreshold(-1);
                app.setMaxThreshold(1);
                app.MinSlider.Value = 0;
                app.MinField.Value = 0;
                app.MaxSlider.Value = 0;
                app.MaxField.Value = 0;
                app.MinField.Enable = 'off';
                app.MaxField.Enable = 'off';
                app.MinSlider.Enable = 'off';
                app.MaxSlider.Enable = 'off';
                app.AddtoshapeButton.Visible = 'off';
            end
        end

        % Menu selected function: ShapeFileMenu
        function ShapeFileMenuSelected(app, event)
            shape = app.wd.getShape();
            if (~isempty(shape))
                [filename, path] = uiputfile({'*.shp',  'Shape file (*.shp)'},'Save shapefile', 'shape.shp');
                shapewrite(shape,strcat(path,filename));
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if (value~="INDEX")
                app.MinField.Enable = 'on';
                app.MaxField.Enable = 'on';
                app.MinSlider.Enable = 'on';
                app.MaxSlider.Enable = 'on';
                app.ShowPreviewButton.Enable = 'on';
                app.ShowNDVIButton.Enable = 'on';
                switch value
                    case 'NDVI'
                        app.setMinThreshold(-0.7);
                        app.setMaxThreshold(-0.3);
                        app.MinSlider.Value = -0.7;
                        app.MinField.Value = -0.7;
                        app.MaxSlider.Value = -0.3;
                        app.MaxField.Value = -0.3;
                        app.ndvi = app.wd.calcNdvi(value,[-0.7,-0.3]);
                        imshow(app.ndvi,'Parent',app.IndexUIAxes);
                        app.showPreview();
                    case 'NDWI2'
                        app.setMinThreshold(0.7);
                        app.setMaxThreshold(1);
                        app.MinSlider.Value = 0.7;
                        app.MinField.Value = 0.7;
                        app.MaxSlider.Value = 1;
                        app.MaxField.Value = 1;
                        app.ndvi = app.wd.calcNdvi(value,[0.7, 1]);
                        imshow(app.ndvi,'Parent',app.IndexUIAxes);
                        app.showPreview();
                    case 'NDWI2M'
                        app.setMinThreshold(-1);
                        app.setMaxThreshold(-0.7);
                        app.MinSlider.Value = -1;
                        app.MinField.Value = -1;
                        app.MaxSlider.Value = -0.7;
                        app.MaxField.Value = -0.7;
                        app.ndvi = app.wd.calcNdvi(value,[-1,-0.7]);
                        imshow(app.ndvi,'Parent',app.IndexUIAxes);
                        app.showPreview();
                end
                app.IndexUIAxes.Title.String = value;
                app.CreateShapeButton.Enable = 'on';
                app.MorphologyMenu.Enable = 'on';
            else
                app.setMinThreshold(-1);
                app.setMaxThreshold(1);
                app.MinSlider.Value = 0;
                app.MinField.Value = 0;
                app.MaxSlider.Value = 0;
                app.MaxField.Value = 0;
                app.resetImage(app.IndexUIAxes,"Index");
                app.resetImage(app.PreviewUIAxes,"Preview");
                app.MinField.Enable = 'off';
                app.MaxField.Enable = 'off';
                app.MinSlider.Enable = 'off';
                app.MaxSlider.Enable = 'off';
                app.ShowPreviewButton.Enable = 'off';
                app.ShowNDVIButton.Enable = 'off';
            end
        end

        % Value changed function: MinField
        function MinFieldValueChanged(app, event)
            index = app.DropDown.Value;
            min = app.MinField.Value;
            max = app.MaxField.Value;
            app.MinSlider.Value = min;
            app.setMinThreshold(min);
            app.ndvi = app.wd.calcNdvi(index,[min,max]);
            imshow(app.ndvi,'Parent',app.IndexUIAxes);
            app.showPreview();
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            index = app.DropDown.Value;
            max = app.MaxSlider.Value;
            min = app.MinField.Value;
            app.MaxField.Value = max;
            app.setMaxThreshold(max);
            app.ndvi = app.wd.calcNdvi(index,[min,max]);
            imshow(app.ndvi,'Parent',app.IndexUIAxes);
            app.showPreview();
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            index = app.DropDown.Value;
            min = app.MinSlider.Value;
            max = app.MaxField.Value;
            app.MinField.Value = min;
            app.setMinThreshold(min);
            app.ndvi = app.wd.calcNdvi(index,[min,max]);
            imshow(app.ndvi,'Parent',app.IndexUIAxes);
            app.showPreview();
        end

        % Value changing function: MinSlider
        function MinSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MinField.Value = changingValue;
        end

        % Value changed function: MaxField
        function MaxFieldValueChanged(app, event)
            index = app.DropDown.Value;
            max = app.MaxField.Value;
            min = app.MinField.Value;
            app.MaxSlider.Value = max;
            app.setMaxThreshold(max);
            app.ndvi = app.wd.calcNdvi(index,[min,max]);
            imshow(app.ndvi,'Parent',app.IndexUIAxes);
            app.showPreview();
        end

        % Value changing function: MaxSlider
        function MaxSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MaxField.Value = changingValue;
        end

        % Button pushed function: CreateShapeButton
        function CreateShapeButtonPushed(app, event)
            if (~isempty(app.wd.getWater()))
                prompt = {'Area max (m^2)','Area min (m^2)'};
                title = 'Create shape';
                answer = str2double(inputdlg(prompt,title,1));
                if (~isempty(answer))
                    answer(isnan(answer)) = 0;
                    msgbox(char("Please, don't touch anything!"),'Wait');
                    if (answer(1) >= answer(2) && answer(1) >0)
                        app.CreateShapeButton.Enable = 'off';
                        [num, shape] = app.wd.createShape(answer(2), answer(1));
                        app.CreateShapeButton.Enable = 'on';
                        app.numFindLabel.Text = strcat("Found: ",string(num));
                        app.FindDropDown.Visible = 'on';
                        if (~isempty(shape))
                            isNew = cell(size(shape));
                            isNew(:) = {""};
                            isNew([shape(:).isNew])= {" New"};
                            app.FindDropDown.Items = cat(2,strcat("Found: ",string(num)) + " " + strcat("New:",string(sum([shape(:).isNew]))),...
                                string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                string(isNew));
                            if (~isempty(app.Selection))
                                crop = app.wd.getCrop();
                                list = unique(extractfield(app.ctr,'DESCR'));
                                if (~isempty(crop.image))
                                    shape = app.wd.cropCTR(list(app.Selection)');
                                    if (~isempty(shape))
                                        found = cell(size(shape));
                                        found(:) = {" not found"};
                                        found([shape(:).found])= {""};
                                        app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                            string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                            string(found'));
                                        app.AddtoshapeButton.Visible = 'on';
                                        app.RemovetoctrButton.Visible = 'on';
                                        app.SaveCTRMenu.Enable = 'on';
                                    else
                                        app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                                        app.AddtoshapeButton.Visible = 'off';
                                        app.RemovetoctrButton.Visible = 'off';
                                        app.SaveCTRMenu.Enable = 'off';
                                    end
                                else
                                    shape = app.wd.getWaterFromCTR(list(app.Selection)');
                                    if (~isempty(shape))
                                        found = cell(size(shape));
                                        found(:) = {" not found"};
                                        found([shape(:).found])= {""};
                                        app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                            string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                            string(found'));
                                        app.AddtoshapeButton.Visible = 'on';
                                        app.RemovetoctrButton.Visible = 'on';
                                        app.SaveCTRMenu.Enable = 'on';
                                    else
                                        app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                                        app.AddtoshapeButton.Visible = 'off';
                                        app.RemovetoctrButton.Visible = 'off';
                                        app.SaveCTRMenu.Enable = 'off';
                                    end
                                end
                            end
                            app.ShapeFileMenu.Enable = 'on';
                            app.UseasmaskButton.Visible = 'on';
                        else
                            app.FindDropDown.Items = strcat("Found: ",string(size(shape,2)));
                            app.AddtoshapeButton.Visible = 'off';
                        end
                    elseif (answer(2) >0 && answer(1)==0)
                        app.CreateShapeButton.Enable = 'off';
                        [num, shape] = app.wd.createShape(answer(2));
                        app.CreateShapeButton.Enable = 'on';
                        app.numFindLabel.Text = strcat("Found: ",string(num));
                        app.FindDropDown.Visible = 'on';
                        if (~isempty(shape))
                            isNew = cell(size(shape));
                            isNew(:) = {""};
                            isNew([shape(:).isNew])= {" New"};
                            app.FindDropDown.Items = cat(2,strcat("Found: ",string(num)) + " " + strcat("New:",string(sum([shape(:).isNew]))),...
                                string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                string(isNew));
                            if (~isempty(app.Selection))
                                crop = app.wd.getCrop();
                                list = unique(extractfield(app.ctr,'DESCR'));
                                if (~isempty(crop.image))
                                    shape = app.wd.cropCTR(list(app.Selection)');
                                    if (~isempty(shape))
                                        found = cell(size(shape));
                                        found(:) = {" not found"};
                                        found([shape(:).found])= {""};
                                        app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                            string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                            string(found'));
                                        app.AddtoshapeButton.Visible = 'on';
                                        app.RemovetoctrButton.Visible = 'on';
                                        app.SaveCTRMenu.Enable = 'on';
                                    else
                                        app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                                        app.AddtoshapeButton.Visible = 'off';
                                        app.RemovetoctrButton.Visible = 'off';
                                        app.SaveCTRMenu.Enable = 'off';
                                    end
                                else
                                    shape = app.wd.getWaterFromCTR(list(app.Selection)');
                                    if (~isempty(shape))
                                        found = cell(size(shape));
                                        found(:) = {" not found"};
                                        found([shape(:).found])= {""};
                                        app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                            string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                            string(found'));
                                        app.AddtoshapeButton.Visible = 'on';
                                        app.RemovetoctrButton.Visible = 'on';
                                        app.SaveCTRMenu.Enable = 'on';
                                    else
                                        app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                                        app.AddtoshapeButton.Visible = 'off';
                                        app.RemovetoctrButton.Visible = 'off';
                                        app.SaveCTRMenu.Enable = 'off';
                                    end
                                end
                            end
                            app.ShapeFileMenu.Enable = 'on';
                            app.UseasmaskButton.Visible = 'on';
                        else
                            app.FindDropDown.Items = strcat("Found: ",string(size(shape,2)));
                            app.AddtoshapeButton.Visible = 'off';
                        end
                    elseif (answer(2) ==0 && answer(1)==0)
                        app.CreateShapeButton.Enable = 'off';
                        [num, shape] = app.wd.createShape();
                        app.CreateShapeButton.Enable = 'on';
                        app.numFindLabel.Text = strcat("Found: ",string(num));
                        app.FindDropDown.Visible = 'on';
                        if (~isempty(shape))
                            isNew = cell(size(shape));
                            isNew(:) = {""};
                            isNew([shape(:).isNew])= {" New"};
                            app.FindDropDown.Items = cat(2,strcat("Found: ",string(num)) + " " + strcat("New:",string(sum([[shape(:).isNew]]))),...
                                string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                string(isNew));
                            if (~isempty(app.Selection))
                                crop = app.wd.getCrop();
                                list = unique(extractfield(app.ctr,'DESCR'));
                                if (~isempty(crop.image))
                                    shape = app.wd.cropCTR(list(app.Selection)');
                                    if (~isempty(shape))
                                        found = cell(size(shape));
                                        found(:) = {" not found"};
                                        found([shape(:).found])= {""};
                                        app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                            string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                            string(found'));
                                        app.AddtoshapeButton.Visible = 'on';
                                        app.RemovetoctrButton.Visible = 'on';
                                        app.SaveCTRMenu.Enable = 'on';
                                    else
                                        app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                                        app.AddtoshapeButton.Visible = 'off';
                                        app.RemovetoctrButton.Visible = 'off';
                                        app.SaveCTRMenu.Enable = 'off';
                                    end
                                else
                                    shape = app.wd.getWaterFromCTR(list(app.Selection)');
                                    if (~isempty(shape))
                                        found = cell(size(shape));
                                        found(:) = {" not found"};
                                        found([shape(:).found])= {""};
                                        app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                            string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                            string(found'));
                                        app.AddtoshapeButton.Visible = 'on';
                                        app.RemovetoctrButton.Visible = 'on';
                                        app.SaveCTRMenu.Enable = 'on';
                                    else
                                        app.CropDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                                        app.AddtoshapeButton.Visible = 'off';
                                        app.RemovetoctrButton.Visible = 'off';
                                        app.SaveCTRMenu.Enable = 'off';
                                    end
                                end
                            end
                            app.ShapeFileMenu.Enable = 'on';
                            app.UseasmaskButton.Visible = 'on';
                        else
                            app.FindDropDown.Items = strcat("Found: ",string(size(shape,2)));
                            app.AddtoshapeButton.Visible = 'off';
                        end
                    end
                    close;
                end
            end
        end

        % Menu selected function: ClosingMenu
        function ClosingMenuSelected(app, event)
            if (~isempty(app.wd.getWater()))
                app.wd.applyMorphClose();
                app.showPreview();
            end
        end

        % Menu selected function: CleaningMenu
        function CleaningMenuSelected(app, event)
            if (~isempty(app.wd.getWater()))
                app.wd.applyMorphClean();
                app.showPreview();
            end
        end

        % Button pushed function: ShowImageButton
        function ShowImageButtonPushed(app, event)
            i = app.wd.getImage();
            if (~isempty(i))
                figure('Name','Image'),imshow(i);
            end
        end

        % Button pushed function: ShowPreviewButton
        function ShowPreviewButtonPushed(app, event)
            if (~isempty(app.preview))
                figure('Name','Preview'),imshow(app.preview);
            end
        end

        % Button pushed function: ShowNDVIButton
        function ShowNDVIButtonButtonPushed(app, event)
            if (~isempty(app.ndvi))
                figure('Name','Normalized Difference Vegetation Index'),imshow(app.ndvi);
            end
        end

        % Button pushed function: ShowCropButton
        function ShowCropButtonButtonPushed(app, event)
            crop = app.wd.getCrop();
            if (~isempty(crop.image))
                figure('Name','Crop'),imshow(crop.image(:,:,1:3));
            end
        end

        % Value changed function: FindDropDown
        function FindDropDownValueChanged(app, event)
            value = extractBefore(app.FindDropDown.Value,":");
            if (~strcmpi(value,'Found'))
                [image, area] = app.wd.showElement(str2double(value),30);
                figure('Name',strcat('Element: ',value)+"; Area: " + area),imshow(image);
            end
        end

        % Button pushed function: UseasmaskButton
        function UseasmaskButtonPushed(app, event)
            value = extractBefore(app.FindDropDown.Value,":");
            if (~strcmpi(value,'Found'))
                value = str2double(value);
                app.wd.removeElement(value);
                app.showPreview();
                shape = app.wd.getShape();
                isNew = cell(size(shape));
                isNew(:) = {""};
                isNew([shape(:).isNew])= {" New"};
                app.FindDropDown.Items = cat(2,strcat("Found: ",string(size(shape,2))) + " " + strcat("New:",string(sum([shape(:).isNew]))),...
                    string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                    string(isNew));
            end
        end

        % Menu selected function: ResetAllMenu
        function ResetAllMenuSelected(app, event)
            app.preview = [];
            app.ndvi = [];
            app.ctr = {};
            app.Selection = [];
            app.maskSelection = [];
            app.wd = WaterDetector();
            app.ResetImageSizeMenu.Enable = "off";
            app.resetImage(app.CropUIAxes,"Crop");
            app.resetImage(app.IndexUIAxes,"Index");
            app.resetImage(app.PreviewUIAxes,"Preview");
            app.resetImage(app.ImageUIAxes,"Image");
            app.ShowCropButton.Enable = 'off';
            app.ShowPreviewButton.Enable = 'off';
            app.ShowNDVIButton.Enable = 'off';
            app.ShowImageButton.Enable = 'off';
            app.ShapeFileMenu.Enable = 'off';
            app.ImageMenu.Enable = 'off';
            app.MaskMenu.Enable = 'off';
            app.MorphologyMenu.Enable = 'off';
            app.numFindLabel.Text = "";
            app.CreateShapeButton.Enable = 'off';
            app.FindDropDown.Visible = 'off';
            app.FindDropDown.Items = {};
            app.UseasmaskButton.Visible = 'off';
            app.DropDown.Value = 'INDEX';
            app.DropDown.Enable = 'off';
            app.setMinThreshold(-1);
            app.setMaxThreshold(1);
            app.MinSlider.Value = 0;
            app.MinField.Value = 0;
            app.MaxSlider.Value = 0;
            app.MaxField.Value = 0;
            app.MinField.Enable = 'off';
            app.MaxField.Enable = 'off';
            app.MinSlider.Enable = 'off';
            app.MaxSlider.Enable = 'off';
            app.OpenCTRMenu.Enable = 'off';
            app.ChangeCTRfieldsMenu.Enable = 'off';
            app.numCTRLabel.Text = "";
            app.numFindLabel.Text = "";
            app.ShowMaskMenu.Enable = 'off';
            app.byCTRfieldsMenu.Enable = 'off';
            app.CTRDropDown.Visible = 'off';
            app.CTRDropDown.Items = {};
            app.AddtoshapeButton.Visible = 'off';
            app.SaveCTRMenu.Enable = 'off';
            app.RemovetoctrButton.Visible = 'off';
            
        end

        % Menu selected function: OpenCTRMenu
        function OpenCTRMenuSelected(app, event)
            if (~isempty(app.wd.getImage()))
                [filename, path] = uigetfile({'*.shp',  'Shape file (*.shp)';...
                    '*.*',  'All Files (*.*)'});
                if (ischar(filename) && ischar(path))
                    msgbox(char("Please, don't touch anything!"),'Wait');
                    c = shaperead(strcat(path,filename));
                    close;
                    if (~isempty(c))
                        app.ctr = c;
                        list = unique(extractfield(app.ctr,'DESCR'));
                        selection = listdlg('Name','Import from CTR', 'PromptString','Select CTR elements:', ...
                            'SelectionMode','multiple','OKString','Select','ListString',list);
                        if(~isempty(selection))
                            msgbox(char("Please, don't touch anything!"),'Wait');
                            [num, app.ctr] = app.wd.importCTR(app.ctr,string(list(selection)));
                            close;
                            app.Selection = find(ismember(unique(extractfield(app.ctr,'DESCR')),list(selection)));
                            list = unique(extractfield(app.ctr,'DESCR'));
                            if (size(num,2) > 1)
                                app.CTRDropDown.Visible = 'on';
                                shape = app.wd.cropCTR(list(app.Selection)');
                                app.numCTRLabel.Text = strcat(strcat("CTR: ",string(num(1))),strcat("; CTR Crop :",string(size(shape,1))));
                                if (~isempty(shape))
                                    found = cell(size(shape));
                                    found(:) = {" not found"};
                                    found([shape(:).found])= {""};
                                    app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                            string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                            string(found'));
                                    app.SaveCTRMenu.Enable = 'on';
                                    app.RemovetoctrButton.Visible = 'on';
                                else
                                    app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                                    app.SaveCTRMenu.Enable = 'off';
                                    app.RemovetoctrButton.Visible = 'off';
                                end
                            else
                                app.numCTRLabel.Text = strcat("CTR: ",string(num));
                                app.CTRDropDown.Visible = 'on';
                                shape = app.wd.getWaterFromCTR(list(app.Selection)');
                                if (~isempty(shape))
                                    found = cell(size(shape));
                                    found(:) = {" not found"};
                                    found([shape(:).found])= {""};
                                    app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                            string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                            string(found'));
                                    app.SaveCTRMenu.Enable = 'on';
                                    app.RemovetoctrButton.Visible = 'on';
                                else
                                    app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                                    app.SaveCTRMenu.Enable = 'off';
                                    app.RemovetoctrButton.Visible = 'off';
                                end
                            end
                            shape = app.wd.getShape();
                            if (~isempty(shape))
                                isNew = cell(size(shape));
                                isNew(:) = {""};
                                isNew([shape(:).isNew])= {" New"};
                                app.FindDropDown.Items = cat(2,strcat("Found: ",string(size(shape,2))) + " " + strcat("New:",string(sum([[shape(:).isNew]]))),...
                                    string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                    string(isNew));
                                app.AddtoshapeButton.Visible = 'on';
                            else
                                app.FindDropDown.Items = strcat("Found: ",string(size(shape,2)));
                                app.AddtoshapeButton.Visible = 'off';
                            end
                        elseif (isempty(app.Selection))
                            app.wd.removeCTR();
                            app.CTRDropDown.Visible = 'off';
                            app.numCTRLabel.Text = "";
                            app.AddtoshapeButton.Visible = 'off';
                            app.RemovetoctrButton.Visible = 'off';
                            app.SaveCTRMenu.Enable = 'off';
                        end
                        app.ChangeCTRfieldsMenu.Enable = 'on';
                        app.byCTRfieldsMenu.Enable = 'on';
                    end
                end
            end
        end

        % Menu selected function: ChangeCTRfieldsMenu
        function ChangeCTRfieldsMenuSelected(app, event)
            if (~isempty(app.ctr))
                list = unique(extractfield(app.ctr,'DESCR'));
                selection = listdlg('Name','Import from CTR', 'PromptString','Select CTR elements:', ...
                    'SelectionMode','multiple','OKString','Select','ListString',list,...
                    'InitialValue',app.Selection);
                if(~isempty(selection))
                    app.Selection=selection;
                    msgbox(char("Please, don't touch anything!"),'Wait');
                    num = app.wd.importCTR(app.ctr,string(list(app.Selection)));
                    close;
                    if (size(num,2) > 1)
                        app.CTRDropDown.Visible = 'on';
                        shape = app.wd.cropCTR(list(app.Selection)');
                        app.numCTRLabel.Text = strcat(strcat("CTR: ",string(num(1))),strcat("; CTR Crop :",string(size(shape,1))));
                        if (~isempty(shape))
                            found = cell(size(shape));
                            found(:) = {" not found"};
                            found([shape(:).found])= {""};
                            app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                string(found'));
                            app.SaveCTRMenu.Enable = 'on';
                            app.RemovetoctrButton.Visible = 'on';
                        else
                            app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                            app.SaveCTRMenu.Enable = 'off';
                            app.RemovetoctrButton.Visible = 'off';
                        end
                    else
                        app.numCTRLabel.Text = strcat("CTR: ",string(num));
                        app.CTRDropDown.Visible = 'on';
                        shape = app.wd.getWaterFromCTR(list(app.Selection)');
                        if (~isempty(shape))
                            found = cell(size(shape));
                            found(:) = {" not found"};
                            found([shape(:).found])= {""};
                            app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                                string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                string(found'));
                            app.SaveCTRMenu.Enable = 'on';
                            app.RemovetoctrButton.Visible = 'on';
                        else
                            app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                            app.SaveCTRMenu.Enable = 'off';
                            app.RemovetoctrButton.Visible = 'off';
                        end
                    end
                    shape = app.wd.getShape();
                    if (~isempty(shape))
                        isNew = cell(size(shape));
                        isNew(:) = {""};
                        isNew([shape(:).isNew])= {" New"};
                        app.FindDropDown.Items = cat(2,strcat("Found: ",string(size(shape,2))) + " " + strcat("New:",string(sum([[shape(:).isNew]]))),...
                                string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                                string(isNew));
                        app.AddtoshapeButton.Visible = 'on';
                    else
                        app.FindDropDown.Items = strcat("Found: ",string(size(shape,2)));
                        app.AddtoshapeButton.Visible = 'off';
                    end
                elseif (isempty(app.Selection))
                    app.wd.removeCTR();
                    app.CTRDropDown.Visible = 'off';
                    app.numCTRLabel.Text = "CTR : ";
                    app.AddtoshapeButton.Visible = 'off';
                    app.RemovetoctrButton.Visible = 'off';
                    app.SaveCTRMenu.Enable = 'off';
                end
            end
        end

        % Menu selected function: byCTRfieldsMenu
        function byCTRfieldsMenuSelected(app, event)
            if (~isempty(app.ctr))
                list = unique(extractfield(app.ctr,'DESCR'));
                selection = listdlg('Name','Mask from CTR', 'PromptString','Select mask element:', ...
                    'SelectionMode','multiple','OKString','Select','ListString',list,...
                    'InitialValue',app.maskSelection);
                if(~isempty(selection))
                    if (~isempty(app.maskSelection))
                        mask = unique(selection(~ismember(selection,app.maskSelection)));
                        if (~isempty(mask))
                            msgbox(char("Please, don't touch anything!"),'Wait');
                            app.wd.maskFromCTR(app.ctr,string(list(mask)));
                            close;
                            app.maskSelection = sort(cat(2,app.maskSelection,mask));
                        end
                    else
                        app.maskSelection = selection;
                        msgbox(char("Please, don't touch anything!"),'Wait');
                        app.wd.maskFromCTR(app.ctr,string(list(app.maskSelection)));
                        close;
                    end
                    if (app.RemovemaskMenu.Enable == "off")
                        app.RemovemaskMenu.Enable = 'on';
                    end
                    if (app.byCTRfieldsMenu_2.Enable == "off")
                        app.byCTRfieldsMenu_2.Enable = 'on';
                    end
                    if (app.ShowMaskMenu.Enable == "off")
                        app.ShowMaskMenu.Enable = 'on';
                    end
                end
            end
        end

        % Menu selected function: byCTRfieldsMenu_2
        function byCTRfieldsMenu_2Selected(app, event)
            if (~isempty(app.maskSelection))
                temp = unique(extractfield(app.ctr,'DESCR'));
                list = unique(temp(app.maskSelection));
                selection = listdlg('Name','Remove Mask from CTR', 'PromptString','Select mask elements:', ...
                    'SelectionMode','multiple','OKString','Select','ListString',list);
                if (~isempty(selection))
                    msgbox(char("Please, don't touch anything!"),'Wait');
                    app.wd.removeMaskFromCTR(app.ctr,string(list(selection)));
                    close;
                    app.maskSelection(app.maskSelection==(find(ismember(temp,list(selection))))) = [];
                    if (isempty(app.maskSelection))
                        app.byCTRfieldsMenu_2.Enable = 'off';
                    end
                    if (isempty(app.wd.getMask()))
                        app.RemovemaskMenu.Enable = 'off';
                        if (app.ShowMaskMenu.Enable == "on")
                            app.ShowMaskMenu.Enable = 'off';
                        end
                    end
                end
            end
        end

        % Menu selected function: ShowMaskMenu
        function ShowMaskMenuSelected(app, event)
            i = app.wd.showMask();
            if (~isempty(i))
                imshow(i);
            end
        end

        % Value changed function: CTRDropDown
        function CTRDropDownValueChanged(app, event)
            value = extractBefore(app.CTRDropDown.Value,":");
            if (~strcmpi(value,'Found'))
                [image, area] = app.wd.showCTRElement(str2double(value),30);
                figure('Name',strcat('Element: ',value)+"; Area: " + area),imshow(image);
            end
        end

        % Button pushed function: AddtoshapeButton
        function AddtoshapeButtonPushed(app, event)
            element = app.CTRDropDown.Value;
            value = extractBefore(app.CTRDropDown.Value,":");
            if (~strcmpi(value,'CTR') && contains(element,"not found"))
                value = str2double(value);
                app.wd.addCTRElementToShape(value);
                app.showPreview();
                shape = app.wd.getShape();
                isNew = cell(size(shape));
                isNew(:) = {""};
                isNew([shape(:).isNew])= {" New"};
                app.FindDropDown.Items = cat(2,strcat("Found: ",string(size(shape,2))) + " " + strcat("New:",string(sum([[shape(:).isNew]]))),...
                    string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                    string(isNew));
                num = str2double(extractAfter(app.numFindLabel.Text,":"));
                app.numFindLabel.Text = strcat("Found: ",string(num+1));
                list = unique(extractfield(app.ctr,'DESCR'));
                crop = app.wd.getCrop();
                shape = {};
                if (~isempty(crop.image))
                    shape = app.wd.cropCTR(list(app.Selection)');
                else
                    shape = app.wd.getWaterFromCTR(list(app.Selection)');
                end
                found = cell(size(shape));
                found(:) = {" not found"};
                found([shape(:).found])= {""};
                app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                    string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                    string(found'));
                app.AddtoshapeButton.Visible = 'on';
                app.RemovetoctrButton.Visible = 'on';
                app.SaveCTRMenu.Enable = 'on';
            end
        end

        % Button pushed function: RemovetoctrButton
        function RemovetoctrButtonPushed(app, event)
            value = extractBefore(app.CTRDropDown.Value,":");
            if (~strcmpi(value,'CTR'))
                value = str2double(value);
                app.wd.removeToCTR(value);
                list = unique(extractfield(app.ctr,'DESCR'));
                crop = app.wd.getCrop();
                shape = {};
                numtotal = 0;
                numcrop = 0;
                if (~isempty(crop.image))
                    shape = app.wd.cropCTR(list(app.Selection)');
                    numtotal = str2double(extractBetween(app.numCTRLabel.Text,":",";"));
                    numcrop = str2double(extractAfter(extractAfter(app.numCTRLabel.Text,";"),":"));
                else
                    shape = app.wd.getWaterFromCTR(list(app.Selection)');
                    numtotal = str2double(extractAfter(app.numCTRLabel.Text,":"));
                end
                if (~isempty(shape))
                    found = cell(size(shape));
                    found(:) = {" not found"};
                    found([shape(:).found])= {""};
                    app.CTRDropDown.Items = cat(2,strcat("CTR: ",string(size(shape,1))) + " " + strcat("not Found :",string(sum(~[shape(:).found]))),...
                        string([shape(:).id]) + " : " + string([shape(:).Area]) +...
                        string(found'));
                    if (numtotal > 0)
                        numtotal = numtotal - 1;
                        app.numCTRLabel.Text=strcat("CTR: ",string(numtotal));    
                    end
                    if (numcrop > 0)
                        numcrop = numcrop - 1;
                        app.numCTRLabel.Text=strcat(app.numCTRLabel.Text,strcat("; CTR Crop :",string(numcrop)));
                    end
                    app.AddtoshapeButton.Visible = 'on';
                    app.RemovetoctrButton.Visible = 'on';
                    app.SaveCTRMenu.Enable = 'on';
                else
                    app.CTRDropDown.Items = strcat("CTR: ",string(size(shape,1)));
                    app.AddtoshapeButton.Visible = 'off';
                    app.RemovetoctrButton.Visible = 'off';
                    app.SaveCTRMenu.Enable = 'off';
                end
            end
        end

        % Menu selected function: SaveCTRMenu
        function SaveCTRMenuSelected(app, event)
            if (~isempty(app.Selection))
                list = unique(extractfield(app.ctr,'DESCR'));
                crop = app.wd.getCrop();
                shape = {};
                if (~isempty(crop.image))
                    shape = app.wd.cropCTR(list(app.Selection)');
                else
                    shape = app.wd.getWaterFromCTR(list(app.Selection)');
                end
                if (~isempty(shape))
                    [filename, path] = uiputfile({'*.shp',  'Shape file (*.shp)'},'Save shapefile', 'shapeCTR.shp');
                    shapewrite(shape,strcat(path,filename));
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaterDetectorGUIUIFigure = uifigure;
            app.WaterDetectorGUIUIFigure.Name = 'Water Detector GUI';
            app.FileMenu = uimenu(app.WaterDetectorGUIUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenGeotifImageMenu = uimenu(app.FileMenu);
            app.OpenGeotifImageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenGeotifImageMenuSelected, true);
            app.OpenGeotifImageMenu.Text = 'Open Geotif Image';
            app.OpenCTRMenu = uimenu(app.FileMenu);
            app.OpenCTRMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenCTRMenuSelected, true);
            app.OpenCTRMenu.Enable = 'off';
            app.OpenCTRMenu.Text = 'Open CTR Shapefile';
            app.ChangeCTRfieldsMenu = uimenu(app.FileMenu);
            app.ChangeCTRfieldsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeCTRfieldsMenuSelected, true);
            app.ChangeCTRfieldsMenu.Enable = 'off';
            app.ChangeCTRfieldsMenu.Text = 'Change CTR fields';
            app.ShapeFileMenu = uimenu(app.FileMenu);
            app.ShapeFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ShapeFileMenuSelected, true);
            app.ShapeFileMenu.Enable = 'off';
            app.ShapeFileMenu.Text = 'Save Shapefile';
            app.SaveCTRMenu = uimenu(app.FileMenu);
            app.SaveCTRMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCTRMenuSelected, true);
            app.SaveCTRMenu.Enable = 'off';
            app.SaveCTRMenu.Text = 'Save CTR';
            app.ResetAllMenu = uimenu(app.FileMenu);
            app.ResetAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetAllMenuSelected, true);
            app.ResetAllMenu.Enable = 'off';
            app.ResetAllMenu.Text = 'Reset All';
            app.ImageMenu = uimenu(app.WaterDetectorGUIUIFigure);
            app.ImageMenu.Enable = 'off';
            app.ImageMenu.Text = 'Image';
            app.CropMenu = uimenu(app.ImageMenu);
            app.CropMenu.MenuSelectedFcn = createCallbackFcn(app, @CropMenuSelected, true);
            app.CropMenu.Text = 'Crop';
            app.ResetImageSizeMenu = uimenu(app.ImageMenu);
            app.ResetImageSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetImageSizeMenuSelected, true);
            app.ResetImageSizeMenu.Enable = 'off';
            app.ResetImageSizeMenu.Text = 'Reset Image Size';
            app.MaskMenu = uimenu(app.WaterDetectorGUIUIFigure);
            app.MaskMenu.Enable = 'off';
            app.MaskMenu.Text = 'Mask';
            app.AddmaskMenu = uimenu(app.MaskMenu);
            app.AddmaskMenu.Text = 'Add mask';
            app.byshapefileMenu = uimenu(app.AddmaskMenu);
            app.byshapefileMenu.MenuSelectedFcn = createCallbackFcn(app, @byshapefileMenuSelected, true);
            app.byshapefileMenu.Text = 'by shapefile';
            app.byeditorMenu = uimenu(app.AddmaskMenu);
            app.byeditorMenu.MenuSelectedFcn = createCallbackFcn(app, @byeditorMenuSelected, true);
            app.byeditorMenu.Text = 'by editor';
            app.byCTRfieldsMenu = uimenu(app.AddmaskMenu);
            app.byCTRfieldsMenu.MenuSelectedFcn = createCallbackFcn(app, @byCTRfieldsMenuSelected, true);
            app.byCTRfieldsMenu.Enable = 'off';
            app.byCTRfieldsMenu.Text = 'by CTR fields';
            app.RemovemaskMenu = uimenu(app.MaskMenu);
            app.RemovemaskMenu.Enable = 'off';
            app.RemovemaskMenu.Text = 'Remove mask';
            app.byshapefileMenu_2 = uimenu(app.RemovemaskMenu);
            app.byshapefileMenu_2.MenuSelectedFcn = createCallbackFcn(app, @byshapefileMenu_2Selected, true);
            app.byshapefileMenu_2.Text = 'by shapefile';
            app.byeditorMenu_2 = uimenu(app.RemovemaskMenu);
            app.byeditorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @byeditorMenu_2Selected, true);
            app.byeditorMenu_2.Text = 'by editor';
            app.byCTRfieldsMenu_2 = uimenu(app.RemovemaskMenu);
            app.byCTRfieldsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @byCTRfieldsMenu_2Selected, true);
            app.byCTRfieldsMenu_2.Enable = 'off';
            app.byCTRfieldsMenu_2.Text = 'by CTR fields';
            app.ShowMaskMenu = uimenu(app.MaskMenu);
            app.ShowMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowMaskMenuSelected, true);
            app.ShowMaskMenu.Enable = 'off';
            app.ShowMaskMenu.Text = 'Show Mask';
            app.MorphologyMenu = uimenu(app.WaterDetectorGUIUIFigure);
            app.MorphologyMenu.Enable = 'off';
            app.MorphologyMenu.Text = 'Morphology';
            app.CleaningMenu = uimenu(app.MorphologyMenu);
            app.CleaningMenu.MenuSelectedFcn = createCallbackFcn(app, @CleaningMenuSelected, true);
            app.CleaningMenu.Text = 'Cleaning';
            app.ClosingMenu = uimenu(app.MorphologyMenu);
            app.ClosingMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosingMenuSelected, true);
            app.ClosingMenu.Text = 'Closing';
            app.CropUIAxes = uiaxes(app.WaterDetectorGUIUIFigure);
            title(app.CropUIAxes, 'Crop')
            app.CropUIAxes.CLim = [0 1];
            app.IndexUIAxes = uiaxes(app.WaterDetectorGUIUIFigure);
            title(app.IndexUIAxes, 'Index')
            app.IndexUIAxes.CLim = [0 1];
            app.MinSliderLabel = uilabel(app.WaterDetectorGUIUIFigure);
            app.MinSliderLabel.Enable = 'off';
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.WaterDetectorGUIUIFigure);
            app.MinSlider.Limits = [-1 1];
            app.MinSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.MinSlider.MajorTickLabels = {'-1', '-0.5', '0', '0.5', '1'};
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MinSlider.ValueChangingFcn = createCallbackFcn(app, @MinSliderValueChanging, true);
            app.MinSlider.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MinSlider.Enable = 'off';
            app.MaxSliderLabel = uilabel(app.WaterDetectorGUIUIFigure);
            app.MaxSliderLabel.Enable = 'off';
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.WaterDetectorGUIUIFigure);
            app.MaxSlider.Limits = [-1 1];
            app.MaxSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.MaxSlider.MajorTickLabels = {'-1', '-0.5', '0', '0.5', '1'};
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.ValueChangingFcn = createCallbackFcn(app, @MaxSliderValueChanging, true);
            app.MaxSlider.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MaxSlider.Enable = 'off';
            app.DropDown = uidropdown(app.WaterDetectorGUIUIFigure);
            app.DropDown.Items = {'INDEX', 'NDVI', 'NDWI2', 'NDWI2M'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'INDEX';
            app.ImageUIAxes = uiaxes(app.WaterDetectorGUIUIFigure);
            title(app.ImageUIAxes, 'Image')
            app.ImageUIAxes.CLim = [0 1];
            app.MaxField = uieditfield(app.WaterDetectorGUIUIFigure, 'numeric');
            app.MaxField.Limits = [-1 1];
            app.MaxField.ValueChangedFcn = createCallbackFcn(app, @MaxFieldValueChanged, true);
            app.MaxField.Enable = 'off';
            app.MinField = uieditfield(app.WaterDetectorGUIUIFigure, 'numeric');
            app.MinField.Limits = [-1 1];
            app.MinField.ValueChangedFcn = createCallbackFcn(app, @MinFieldValueChanged, true);
            app.MinField.Enable = 'off';
            app.PreviewUIAxes = uiaxes(app.WaterDetectorGUIUIFigure);
            title(app.PreviewUIAxes, 'Preview')
            app.PreviewUIAxes.CLim = [0 1];
            app.CreateShapeButton = uibutton(app.WaterDetectorGUIUIFigure, 'push');
            app.CreateShapeButton.ButtonPushedFcn = createCallbackFcn(app, @CreateShapeButtonPushed, true);
            app.CreateShapeButton.Enable = 'off';
            app.CreateShapeButton.Text = 'Create Shape';
            app.ShowImageButton = uibutton(app.WaterDetectorGUIUIFigure, 'push');
            app.ShowImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowImageButtonPushed, true);
            app.ShowImageButton.Enable = 'off';
            app.ShowImageButton.Text = 'Show Image';
            app.ShowPreviewButton = uibutton(app.WaterDetectorGUIUIFigure, 'push');
            app.ShowPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPreviewButtonPushed, true);
            app.ShowPreviewButton.Enable = 'off';
            app.ShowPreviewButton.Text = 'Show Image';
            app.ShowNDVIButton = uibutton(app.WaterDetectorGUIUIFigure, 'push');
            app.ShowNDVIButton.ButtonPushedFcn = createCallbackFcn(app, @ShowNDVIButtonButtonPushed, true);
            app.ShowNDVIButton.Enable = 'off';
            app.ShowNDVIButton.Text = 'Show Image';
            app.ShowCropButton = uibutton(app.WaterDetectorGUIUIFigure, 'push');
            app.ShowCropButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCropButtonButtonPushed, true);
            app.ShowCropButton.Enable = 'off';
            app.ShowCropButton.Text = 'Show Image';
            app.numFindLabel = uilabel(app.WaterDetectorGUIUIFigure);
            app.numFindLabel.Text = '';
            app.FindDropDown = uidropdown(app.WaterDetectorGUIUIFigure);
            app.FindDropDown.Items = {};
            app.FindDropDown.ValueChangedFcn = createCallbackFcn(app, @FindDropDownValueChanged, true);
            app.FindDropDown.Visible = 'off';
            app.FindDropDown.Value = {};
            app.UseasmaskButton = uibutton(app.WaterDetectorGUIUIFigure, 'push');
            app.UseasmaskButton.ButtonPushedFcn = createCallbackFcn(app, @UseasmaskButtonPushed, true);
            app.UseasmaskButton.Visible = 'off';
            app.UseasmaskButton.Text = 'Use as mask';
            app.numCTRLabel = uilabel(app.WaterDetectorGUIUIFigure);
            app.numCTRLabel.Text = '';
            app.CTRDropDown = uidropdown(app.WaterDetectorGUIUIFigure);
            app.CTRDropDown.Items = {};
            app.CTRDropDown.ValueChangedFcn = createCallbackFcn(app, @CTRDropDownValueChanged, true);
            app.CTRDropDown.Visible = 'off';
            app.CTRDropDown.Value = {};
            app.AddtoshapeButton = uibutton(app.WaterDetectorGUIUIFigure, 'push');
            app.AddtoshapeButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoshapeButtonPushed, true);
            app.AddtoshapeButton.Visible = 'off';
            app.AddtoshapeButton.Text = 'Add';
            app.RemovetoctrButton = uibutton(app.WaterDetectorGUIUIFigure, 'push');
            app.RemovetoctrButton.ButtonPushedFcn = createCallbackFcn(app, @RemovetoctrButtonPushed, true);
            app.RemovetoctrButton.Visible = 'off';
            app.RemovetoctrButton.Text = 'Remove';
        end
    end

    methods (Access = public)

        % Construct app
        function app = waterDetectorGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaterDetectorGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaterDetectorGUIUIFigure)
        end
    end
end